classdef CourseWorkApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HistogramButton      matlab.ui.control.Button
        LoadGrayImageButton  matlab.ui.control.Button
        LoadImageButton      matlab.ui.control.Button
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            try
            global I;
            [filename,pathname]=uigetfile({'*.png';'*.tiff';'*.jpg'},'Load Image');
            filename=strcat(pathname,filename);
            I=imread(filename);
            imshow(I,'Parent',app.UIAxes)
            if isequal(filename,0)
                warning('No Image Selected');
            end

            
            catch END
                disp(['Error: ' END.message]);
                
            end
   
        end

        % Button pushed function: LoadGrayImageButton
        function LoadGrayImageButtonPushed(app, event)
            global I;
            global grayImage;
            grayImage=rgb2gray(I);
            imshow(grayImage,'Parent',app.UIAxes2)
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            global I;
            J=rgb2gray(I);
            Intensity=Myhist(J)
            bar(app.UIAxes3,0:255,Intensity)%plotting on the 3rd axesUI,0:255 is the Xaxis and Intensity is the Y axis
            xlabel(app.UIAxes3, 'Intensity Level');
            ylabel(app.UIAxes3, 'Frequency');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gray Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadGrayImageButton = uibutton(app.UIFigure, 'push');
            app.LoadGrayImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGrayImageButtonPushed, true);
            app.LoadGrayImageButton.Text = 'Load Gray Image';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CourseWorkApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end