classdef CourseWorkApplication1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ViewButton               matlab.ui.control.Button
        ContrastValueandWeightsTextArea  matlab.ui.control.TextArea
        ContrastValueandWeightsTextAreaLabel  matlab.ui.control.Label
        MaximumContrastButton    matlab.ui.control.Button
        LoadOriginalImageButton  matlab.ui.control.Button
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOriginalImageButton
        function LoadOriginalImageButtonPushed(app, event)
            try
            global I;
            [filename,pathname]=uigetfile({'*.png';'*.tiff';'*.jpg'},'Load Image');
            filename = fullfile(pathname, filename);
            I=imread(filename);
            imshow(I,'Parent',app.UIAxes4)
            if isequal(filename,0)
                warning('No Image Selected');
            end

            
            catch END
                disp(['Error: ' END.message]);
                
            end
        end

        % Button pushed function: MaximumContrastButton
        function MaximumContrastButtonPushed(app, event)
            global I;
            global bestImage;
            global bestWeights;
            global maxContrast;
            [bestImage,bestWeights,maxContrast]=Myrgb2gray(I);
            imshow(bestImage,'Parent',app.UIAxes3)
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            global maxContrast;
            global bestWeights;
             app.ContrastValueandWeightsTextArea.Value = strcat('The best Weights for (RGB) are ', num2str(bestWeights), ', Max Contrast: ', num2str(maxContrast));
        end

        % Value changed function: ContrastValueandWeightsTextArea
        function ContrastValueandWeightsTextAreaValueChanged(app, event)
            global maxContrast;
             app.ContrastValueandWeightsTextArea.Value=num2str(maxContrast);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image With Best Contrast')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Original Image')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.LoadOriginalImageButton = uibutton(app.UIFigure, 'push');
            app.LoadOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOriginalImageButtonPushed, true);
            app.LoadOriginalImageButton.Text = 'Load Original Image';
            app.MaximumContrastButton = uibutton(app.UIFigure, 'push');
            app.MaximumContrastButton.ButtonPushedFcn = createCallbackFcn(app, @MaximumContrastButtonPushed, true);
            app.MaximumContrastButton.Text = 'Maximum Contrast';
            app.ContrastValueandWeightsTextAreaLabel = uilabel(app.UIFigure);
            app.ContrastValueandWeightsTextAreaLabel.Text = 'Contrast Value and Weights';
            app.ContrastValueandWeightsTextArea = uitextarea(app.UIFigure);
            app.ContrastValueandWeightsTextArea.ValueChangedFcn = createCallbackFcn(app, @ContrastValueandWeightsTextAreaValueChanged, true);
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CourseWorkApplication1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end