classdef CourseWorkApplication2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TotaNumberOfRBCTextArea       matlab.ui.control.TextArea
        TotaNumberOfRBCTextAreaLabel  matlab.ui.control.Label
        NumberOfRBCButton             matlab.ui.control.Button
        SegementedRBCButton           matlab.ui.control.Button
        ImageButton                   matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
               try
                    % Open a file dialog to select a TIFF image
                    [filename, pathname] = uigetfile({'*.tif'}, 'Load Image');
                    
                    % Check if the user canceled the operation
                    if isequal(filename, 0)
                        warning('No Image Selected');
                        return;
                    end
            
                    % Read the selected image
                    global I;
                   filename = fullfile(pathname, filename);
                    I = imread(filename);
            
                    % Display the image in the UIAxes
                    imshow(I, 'Parent', app.UIAxes);
                    
                catch exception
                    % Handle any errors that may occur during image loading
                    disp(['Error: ' exception.message]);
                end
        end

        % Button pushed function: SegementedRBCButton
        function SegementedRBCButtonPushed(app, event)
            global I;
            global num
            
            [outImage,num]=myrbcDetection(I);
            imshow(outImage,'Parent',app.UIAxes3);

        end

        % Callback function
        function TotalNumberofRBCEditFieldValueChanged(app, event)
            global num;
            value = app.TotalNumberofRBCEditField.Value;
            disp(['Total number of RBCs: ' num2str(num)]);
        end

        % Button pushed function: NumberOfRBCButton
        function NumberOfRBCButtonPushed(app, event)
            global num;
            app.TotaNumberOfRBCTextArea.Value= num2str(num);
        end

        % Value changed function: TotaNumberOfRBCTextArea
        function TotaNumberOfRBCTextAreaValueChanged(app, event)
            app.TotaNumberOfRBCTextArea.Value= num2str(num);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image Showing Center of RBC')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.SegementedRBCButton = uibutton(app.UIFigure, 'push');
            app.SegementedRBCButton.ButtonPushedFcn = createCallbackFcn(app, @SegementedRBCButtonPushed, true);
            app.SegementedRBCButton.Text = 'Segemented RBC';
            app.NumberOfRBCButton = uibutton(app.UIFigure, 'push');
            app.NumberOfRBCButton.ButtonPushedFcn = createCallbackFcn(app, @NumberOfRBCButtonPushed, true);
            app.NumberOfRBCButton.Text = 'Number Of RBC';
            app.TotaNumberOfRBCTextAreaLabel = uilabel(app.UIFigure);
            app.TotaNumberOfRBCTextAreaLabel.Text = 'Tota Number Of RBC';
            app.TotaNumberOfRBCTextArea = uitextarea(app.UIFigure);
            app.TotaNumberOfRBCTextArea.ValueChangedFcn = createCallbackFcn(app, @TotaNumberOfRBCTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CourseWorkApplication2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end